classdef UTM_Conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BrowseButton           matlab.ui.control.Button
        InputEditFieldLabel    matlab.ui.control.Label
        InputEditField         matlab.ui.control.EditField
        UTMParametersPanel     matlab.ui.container.Panel
        UTMZoneEditFieldLabel  matlab.ui.control.Label
        UTMZoneEditField       matlab.ui.control.NumericEditField
        NorthCheckBox          matlab.ui.control.CheckBox
        SouthCheckBox          matlab.ui.control.CheckBox
        CONVERTButton          matlab.ui.control.Button
        dxEditFieldLabel       matlab.ui.control.Label
        dxEditField            matlab.ui.control.EditField
        dyEditFieldLabel       matlab.ui.control.Label
        dyEditField            matlab.ui.control.EditField
        CheckButton            matlab.ui.control.Button
        RestartButton          matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        Lamp_2                 matlab.ui.control.Lamp
        Lamp_3                 matlab.ui.control.Lamp
        StatusTextAreaLabel    matlab.ui.control.Label
        StatusTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = public)
         %Go top left toolbar-->Property-->Public
        FILE101 % Input data
    end
    
    methods (Access = public)
        
        function [Xq_Utm,Yq_Utm,Zq_Utm,dx,dy]=UTM_Conv_km(app,DATA,UTM_Zone,UTM_hemisphere,Xsq,Ysq)

%DATA=load(INPUT);
%UTM_Zone=input('Choose UTM Zone (Only Number): ');
%During UTM hemisphere selection please write 'N' or 'S'
%UTM_hemisphere=input('Choose UTM Hemisphere (N or S): ');
[X_utm,Y_utm,UTM_Zone,UTM_hemisphere] = wgs2utm(app,DATA(:,2),DATA(:,1),UTM_Zone,UTM_hemisphere);

% %km conversion
% X_utm_km=X_utm./1000;
% Y_utm_km=Y_utm./1000;

%km is not working with Parker, therefore m is used.
X_utm_km=X_utm./1;
Y_utm_km=Y_utm./1;

%Processing time sigbificantly increased
% sorted_X_utm_km=sort(X_utm_km,'ascend');
% dx=sorted_X_utm_km(2)-sorted_X_utm_km(1);
% sorted_Y_utm_km=sort(Y_utm_km,'ascend');
% dy=sorted_Y_utm_km(2)-sorted_Y_utm_km(1);


%subtract min value to start km from 0
%X_utm_km=X_utm_km-min(X_utm_km);
%Y_utm_km=Y_utm_km-min(Y_utm_km);

%auto adjustment of sampling interval
[M,N]=size(Xsq);
 dx=(max(X_utm_km)-min(X_utm_km))./N;
[M,N]=size(Ysq);
 dy=(max(Y_utm_km)-min(Y_utm_km))./M;


%%
%MAPPING
[Xq_Utm,Yq_Utm]=meshgrid(min(X_utm_km):dx:max(X_utm_km), min(Y_utm_km):dy:max(Y_utm_km));
Zq_Utm=griddata(X_utm_km,Y_utm_km,DATA(:,3),Xq_Utm,Yq_Utm);


%filling NaN values along X-axis direction
%Zq_Utm = fillmissing(Zq_Utm,'linear','EndValues','nearest');
Zq_Utm = fillmissing(Zq_Utm,'movmedian',13); 
%if NaN values are remained across Y-axis direction
%Zq_Utm = fillmissing(Zq_Utm','linear','EndValues','nearest');
Zq_Utm = fillmissing(Zq_Utm','movmedian',13); 
Zq_Utm=Zq_Utm';

[MM,NN]=size(Zq_Utm);
for i=1:MM
    for j=1:NN
if isnan(Zq_Utm(i,j))==1
    Zq_Utm(i,j)=median(median(Zq_Utm,'omitnan'));
end
    end
end

figure;
h=pcolor(Xq_Utm,Yq_Utm,Zq_Utm);
shading interp
colormap jet
xlabel('UTM Easting (m)','FontSize',20,'FontName','Times New Roman')
ylabel('UTM Northing (m)','FontSize',20,'FontName','Times New Roman')
title('Cropped DATA UTM','FontSize',20)
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

function  [x,y,utmzone,utmhemi] = wgs2utm(app,Lat,Lon,utmzone,utmhemi)
% -------------------------------------------------------------------------
% [x,y,utmzone] = wgs2utm(Lat,Lon,Zone)
%
% Description:
%    Convert WGS84 coordinates (Latitude, Longitude) into UTM coordinates
%    (northing, easting) according to (optional) input UTM zone and
%    hemisphere.
%
% Input:
%    Lat: WGS84 Latitude scalar, vector or array in decimal degrees.  
%    Lon: WGS84 Longitude scalar, vector or array in decimal degrees. 
%    utmzone (optional): UTM longitudinal zone. Scalar or same size as Lat
%       and Lon.
%    utmhemi (optional): UTM hemisphere as a single character, 'N' or 'S',
%       or array of 'N' or 'S' characters of same size as Lat and Lon.
%
% Output:
%    x: UTM easting in meters.
%    y: UTM northing in meters.
%    utmzone: UTM longitudinal zone.
%    utmhemi: UTM hemisphere as array of 'N' or 'S' characters. 
%
% Author notes:
%    I downloaded and tried deg2utm.m from Rafael Palacios but found
%    differences of up to 1m with my reference converters in southern
%    hemisphere so I wrote my own code based on "Map Projections - A
%    Working Manual" by J.P. Snyder (1987). Quick quality control performed
%    only by comparing with LINZ converter
%    (www.linz.govt.nz/apps/coordinateconversions/) and Chuck Taylor's 
%    (http://home.hiwaay.net/~taylorc/toolbox/geography/geoutm.html) on a 
%    few test points, so use results with caution. Equations not suitable
%    for a latitude of +/- 90deg.
%
%    UPDATE: Following requests, this new version allows forcing UTM zone
%    in input.
%
% Examples:
%
%    % set random latitude and longitude arrays
%    Lat= 90.*(2.*rand(3)-1)
%    Lon= 180.*(2.*rand(3)-1)
%
%    % let the function find appropriate UTM zone and hemisphere from data 
%    [x1,y1,utmzone1,utmhemi1] = wgs2utm(Lat,Lon)
%
%    % forcing unique UTM zone and hemisphere for all data entries
%    % note: resulting easting and northing are way off the usual values
%    [x2,y2,utmzone2,utmhemi2] = wgs2utm(Lat,Lon,60,'S')
%
%    % forcing different UTM zone and hemisphere for each data entry
%    % note: resulting easting and northing are way off the usual values
%    utmzone = floor(59.*rand(3))+1
%    utmhemi = char(78 + 5.*round(rand(3)))
%    [x3,y3,utmzone3,utmhemi3] = wgs2utm(Lat,Lon,utmzone,utmhemi)
%
% Author: 
%   Alexandre Schimel
%   MetOcean Solutions Ltd
%   New Plymouth, New Zealand
%
% Version 2:
%   February 2011
%-------------------------------------------------------------------------
%% Argument checking
% if ~sum(double(nargin==[2,4]))
%     error('Wrong number of input arguments');return
% end
n1=size(Lat);
n2=size(Lon);
% if (n1~=n2)
%     error('Lat and Lon should have same size');return
% end
if exist('utmzone','var') && exist('utmhemi','var')
    n3=size(utmzone);
    n4=size(utmhemi);
    if (sort(n3)~=sort(n4))
        error('utmzone and utmhemi should have same size');return
    end
    if max(n3)~=1 && max(n3)~=max(n1)
        error('utmzone should have either same size as Lat and Long, or size=1');return
    end
end
% expand utmzone and utmhemi if needed
if exist('utmzone','var') && exist('utmhemi','var')
    n3=size(utmzone);
    n4=size(utmhemi);
    if n3==[1 1]
        utmzone = utmzone.*ones(size(Lat));
        utmhemi = char(utmhemi.*ones(size(Lat)));
    end
end
    
%% coordinates in radians
lat = Lat.*pi./180;
lon = Lon.*pi./180;
%% WGS84 parameters
a = 6378137;           %semi-major axis
b = 6356752.314245;    %semi-minor axis
% b = 6356752.314140;  %GRS80 value, originally used for WGS84 before refinements
e = sqrt(1-(b./a).^2); % eccentricity
%% UTM parameters
% lat0 = 0;                % reference latitude, not used here
if exist('utmzone','var')
    Lon0 = 6.*utmzone-183; % reference longitude in degrees
else
    Lon0 = floor(Lon./6).*6+3; % reference longitude in degrees
end
lon0 = Lon0.*pi./180;      % in radians
k0 = 0.9996;               % scale on central meridian
FE = 500000;              % false easting
if exist('utmhemi','var')
    FN = double(utmhemi=='S').*10000000;
else
    FN = (Lat < 0).*10000000; % false northing 
end
%% Equations parameters
eps = e.^2./(1-e.^2);  % e prime square
% N: radius of curvature of the earth perpendicular to meridian plane
% Also, distance from point to polar axis
N = a./sqrt(1-e.^2.*sin(lat).^2); 
T = tan(lat).^2;                
C = ((e.^2)./(1-e.^2)).*(cos(lat)).^2;
A = (lon-lon0).*cos(lat);                            
% M: true distance along the central meridian from the equator to lat
M = a.*(  ( 1 - e.^2./4 - 3.*e.^4./64 - 5.*e.^6./256 )  .* lat         ...
         -( 3.*e.^2./8 + 3.*e.^4./32 + 45.*e.^6./1024 ) .* sin(2.*lat) ...
         +( 15.*e.^4./256 + 45.*e.^6./1024 )            .* sin(4.*lat) ...
         -(35.*e.^6./3072 )                             .* sin(6.*lat) );
%% easting
x = FE + k0.*N.*(                                  A       ...
                 + (1-T+C)                      .* A.^3./6 ...
                 + (5-18.*T+T.^2+72.*C-58.*eps) .* A.^5./120 );
               
%% northing 
% M(lat0) = 0 so not used in following formula
y = FN + k0.*M + k0.*N.*tan(lat).*(                                     A.^2./2  ...
                                   + (5-T+9.*C+4.*C.^2)              .* A.^4./24 ...
                                   + (61-58.*T+T.^2+600.*C-330.*eps) .* A.^6./720 );
                                 
%% UTM zone
if exist('utmzone','var') && exist('utmhemi','var')
    utmzone = utmzone;
    utmhemi = utmhemi;
else 
   utmzone = floor(Lon0./6)+31;
   utmhemi = char( 83.* (Lat < 0) + 78.* (Lat >= 0) );
end
end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
function [Xq,Yq,Zq,Z_q]=gridding_mapping(app,X,Y,Z,dx,dy)

%gridding
[Xq,Yq]=meshgrid(min(X):dx:max(X), min(Y):dy:max(Y));
Zq=griddata(X,Y,Z,Xq,Yq);

%filling NaN values along X-axis direction
Zq = fillmissing(Zq,'linear','EndValues','nearest');
%if NaN values are remained across Y-axis direction
Zq = fillmissing(Zq','linear','EndValues','nearest');
Z_q=Zq';

%% detrend_2d
%Z_q=detrend_2d(Zq);

%map
figure;
h=pcolor(Xq,Yq,Z_q);
set(h, 'EdgeColor', 'none');
shading interp
colormap jet
%xlabel('Longitude','FontSize',20,'FontName','Times New Roman')
%ylabel('Latitude','FontSize',20,'FontName','Times New Roman')
colorbar;
set(gca,'FontSize',12,'Fontname','times')

     end
     
        function [x,y,z] = grid2xyz(app,Xgrid, Ygrid, Zgrid)
            %GRID2XYZ converts a grid to xyz and removes NaN's
%
%   Syntax:
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   Input:
%   Xgrid = x grid coordinates
%   Ygrid = y grid coordinates
%   Zgrid = z grid coordinates
%
%   Output:
%   x = x coordinate (vector)
%   y = y coordinate (vector)
%   z = z coordinate (vector)
%
%   Example
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   See also 

%% Copyright notice
%   --------------------------------------------------------------------
%   Copyright (C) 2010 Alkyon Hydraulic Consultancy & Research
%       grasmeijerb
%
%       bart.grasmeijer@alkyon.nl	
%
%       P.O. Box 248
%       8300 AE Emmeloord
%       The Netherlands
%
%   This library is free software: you can redistribute it and/or
%   modify it under the terms of the GNU Lesser General Public
%   License as published by the Free Software Foundation, either
%   version 2.1 of the License, or (at your option) any later version.
%
%   This library is distributed in the hope that it will be useful,
%   but WITHOUT ANY WARRANTY; without even the implied warranty of
%   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
%   Lesser General Public License for more details.
%
%   You should have received a copy of the GNU Lesser General Public
%   License along with this library. If not, see <http://www.gnu.org/licenses/>.
%   --------------------------------------------------------------------


%%

x = reshape(Xgrid,[numel(Xgrid),1]);
y = reshape(Ygrid,[numel(Ygrid),1]);
z = reshape(Zgrid,[numel(Zgrid),1]);
mynans = isnan(x) | isnan(y) | isnan(z);
x(mynans) = [];
y(mynans) = [];
z(mynans) = [];
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONVERTButton
        function convert(app, event)
            dx=str2double(app.dxEditField.Value); %defining dx and dy            
            dy=str2double(app.dyEditField.Value);


        if app.NorthCheckBox.Value==true && app.SouthCheckBox.Value==true
             f=errordlg('Please choose North or South hemisphere. If you choose both of them, perhaps you have a perfect imagination.')            
             elseif app.NorthCheckBox.Value==true;                 
             UTM_hemisphere='N';                       
             elseif app.SouthCheckBox.Value==true;
             UTM_hemisphere='S';
             else
             f=errordlg('Please choose one of the hemisphere. The study area must be in the northern or southern hemisphere')
        end
        
        %%
        %UTM CONVERSION CODE
        %INPUT
DATA101=load(app.FILE101);


%Define X,Y,Z
X=DATA101(:,1);
Y=DATA101(:,2);
Z=DATA101(:,3);
%%
%gridding with gridding_mapping func.
[Xq,Yq,Zq,Z_q]=gridding_mapping(app,X,Y,Z,dx,dy);
title('Input Layer Grid','FontSize',20,'FontName','Times New Roman')

%%
%UTM CONVERSION
LONLATZ=[X,Y,Z];
[Xq_Utm,Yq_Utm,Zq_Utm,dx,dy]=UTM_Conv_km(app,LONLATZ,app.UTMZoneEditField.Value,UTM_hemisphere,Xq,Yq);
Z_q=Zq_Utm;
[x_u,y_u,z_u] = grid2xyz(app,Xq_Utm, Yq_Utm, Z_q);

               fid=fopen('UTM.dat','w');
               %fprintf(fid,'%18s %18s %18s\n','UTM_X(m)','UTM_Y(m)','Z(mgal)');               
              for i=1:length(x_u)
                  WR2=[x_u(i),y_u(i),z_u(i)];
              fprintf(fid,'%18f %18f %18f\n', WR2);
              end
              fclose(fid);
        
        
        end

        % Button pushed function: BrowseButton
        function browse101(app, event)
             [file,path]=uigetfile('*.dat');
            
            if isequal(file,0)
                f=errordlg('You have not selected a dat file','Warning!')
            else
                %disp(['User selected', fullfile(path,file)]);
                app.InputEditField.Value=file;
                app.FILE101=fullfile(path,file);
                app.Lamp_3.Color = [0 1 0];
            end
        end

        % Button pushed function: CheckButton
        function check(app, event)
                    dx=str2double(app.dxEditField.Value); %defining dx and dy    
        if isnan(dx) %If NaN is given for m show error dialog
            f=errordlg('dx is empty','Warning!')
            else
                app.Lamp.Color = [0 1 0];
            end
             dy=str2double(app.dyEditField.Value); %defining dx and dy
            if isnan(dy) %If NaN is given for n show error dialog
            f=errordlg('dy is empty','Warning!')
            else
                app.Lamp_2.Color = [0 1 0];
            end
            
            sumappLampColor=app.Lamp.Color+app.Lamp_2.Color;
            
            if sumappLampColor==[0 2 0];
            app.StatusTextArea.Value = {'Prepare yourself to press "RUN"'};
            else
            app.StatusTextArea.Value = {'Opps! Something is missing'};
            end
        end

        % Button pushed function: RestartButton
        function restart(app, event)
            app.Lamp_3.Color = [1 0 0];
            app.Lamp.Color = [1 0 0];
            app.Lamp_2.Color = [1 0 0];
            
            app.dxEditField.Value = ' ';
            app.dyEditField.Value = ' ';
            app.InputEditField.Value=' ';
            app.StatusTextArea.Value = {'Please Press Check!'};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse101, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'text');
            app.UTMParametersPanel = uipanel(app.UIFigure);
            app.UTMParametersPanel.Title = '                        UTM Parameters';
            app.UTMZoneEditFieldLabel = uilabel(app.UTMParametersPanel);
            app.UTMZoneEditFieldLabel.Text = 'UTM Zone';
            app.UTMZoneEditField = uieditfield(app.UTMParametersPanel, 'numeric');
            app.NorthCheckBox = uicheckbox(app.UTMParametersPanel);
            app.NorthCheckBox.Text = 'North';
            app.NorthCheckBox.Value = true;
            app.SouthCheckBox = uicheckbox(app.UTMParametersPanel);
            app.SouthCheckBox.Text = 'South';
            app.CONVERTButton = uibutton(app.UIFigure, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.CONVERTButton.Text = {'CONVERT!'; ''};
            app.dxEditFieldLabel = uilabel(app.UIFigure);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.UIFigure, 'text');
            app.dyEditFieldLabel = uilabel(app.UIFigure);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.UIFigure, 'text');
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @check, true);
            app.CheckButton.Text = 'Check';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @restart, true);
            app.RestartButton.Text = 'Restart';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Value = {'Please Press Check!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UTM_Conv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end