classdef fwg_parker3D_v5mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LayerEditFieldLabel           matlab.ui.control.Label
        LayerEditField                matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        WarningTextArea               matlab.ui.control.TextArea
        dxmEditFieldLabel             matlab.ui.control.Label
        dxmEditField                  matlab.ui.control.EditField
        dymEditFieldLabel             matlab.ui.control.Label
        dymEditField                  matlab.ui.control.EditField
        z0mEditFieldLabel             matlab.ui.control.Label
        z0mEditField                  matlab.ui.control.EditField
        drhokgm3EditFieldLabel        matlab.ui.control.Label
        drhokgm3EditField             matlab.ui.control.EditField
        TrendRemovalCheckBox          matlab.ui.control.CheckBox
        RUNButton                     matlab.ui.control.Button
        nEditFieldLabel               matlab.ui.control.Label
        nEditField                    matlab.ui.control.EditField
        Autoz0CheckBox                matlab.ui.control.CheckBox
        WavenumberEqualizationButton  matlab.ui.control.Button
        UTMConversionButton           matlab.ui.control.Button
        CheckButton                   matlab.ui.control.Button
        RestartButton                 matlab.ui.control.Button
        StatusTextAreaLabel           matlab.ui.control.Label
        StatusTextArea                matlab.ui.control.TextArea
        OperationsMenuLabel           matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_5                        matlab.ui.control.Lamp
        Lamp_6                        matlab.ui.control.Lamp
    end

    
    properties (Access = public)
         %Go top left toolbar-->Property-->Public
        FILE1 %assign the selected file to FILE1
    end
    
     methods (Access = public)
     function [Xq,Yq,Zq,Z_q]=gridding_mapping(app,X,Y,Z,dx,dy)

%gridding
[Xq,Yq]=meshgrid(min(X):dx:max(X), min(Y):dy:max(Y));
Zq=griddata(X,Y,Z,Xq,Yq);

%filling NaN values along X-axis direction
Zq = fillmissing(Zq,'linear','EndValues','nearest');
%if NaN values are remained across Y-axis direction
Zq = fillmissing(Zq','linear','EndValues','nearest');
Z_q=Zq';

%% detrend_2d
%Z_q=detrend_2d(Zq);

%map
figure;
h=pcolor(Xq,Yq,Z_q);
set(h, 'EdgeColor', 'none');
shading interp
colormap jet
%xlabel('Longitude','FontSize',20,'FontName','Times New Roman')
%ylabel('Latitude','FontSize',20,'FontName','Times New Roman')
colorbar;
set(gca,'FontSize',12,'Fontname','times')

     end
     
     function [G]=parker3D(app,Z_q,n,dx,dy,drho,z0)

%-------------------------------------------------------------------------
%INPUT
%Z_q=Gridded layer (m)
%n=Degree of expansion for Parker's Equation
%dx,dy=Sampling interval along x and y directions (m)

%OUTPUT
%G=Gravity anomaly of the given input layer (mgal)
%-------------------------------------------------------------------------


GG=6.67E-11; %gravitational constant [m3 kg-1 s-2]
%n=4; %Degree of series for the expansion of Parker's equation

%Normalization of the Input Layer

maxtopo = max(max(Z_q));
normtopo = Z_q/maxtopo;

%Convert to a wavenumber grid
[Nk,Mk]=size(normtopo);
kkx = pi/dx; kky = pi/dy; %Find max k along x and y directions
ddx = kkx./(Nk - 1); ddy = kky./(Mk - 1); %Find interval in wavenumber domain
kx = 0:ddx:kkx; ky = 0:ddy:kky; %wavenumber in x and y directions

%Generating padded wavenumber grid
for i = 1:1:length(kx)
    for j = 1:1:length(ky)
        wavenumber_grid(i,j)=sqrt(kx(i).^2+ky(j).^2);
    end
end

%For FFT, syymetrical grid is required. Therefore, flipud, fliplr and rot90
%commands have been used.

%For TOPO
Symm_Topo(1:Nk,1:Mk) = normtopo;
Symm_Topo(Nk+1:2.*Nk,1:Mk) = flipud(normtopo);
Symm_Topo(1:Nk,Mk+1:2.*Mk) = fliplr(normtopo);
Symm_Topo(Nk+1:2.*Nk,Mk+1:2.*Mk) = rot90(normtopo,2);

%For Wavenumber
Symm_Wavenumber(1:Nk,1:Mk) = wavenumber_grid;
Symm_Wavenumber(Nk+1:2.*Nk,1:Mk) = flipud(wavenumber_grid);
Symm_Wavenumber(1:Nk,Mk+1:2.*Mk) = fliplr(wavenumber_grid);
Symm_Wavenumber(Nk+1:2.*Nk,Mk+1:2.*Mk) = rot90(wavenumber_grid,2);


%Calculating the inside of the summation symbol
sumtot= 0;

for i = 1:n
    FTOPO=fft2(Symm_Topo.^i);
    sumtot=sumtot+(maxtopo.^i)*((Symm_Wavenumber.^(i-1)).*FTOPO)./factorial(i);
end

%Compute FFT of gravity anomaly of undulated layer
FG = -(2*pi*GG*drho*1.e05)*(exp((abs(z0)).*(-abs(Symm_Wavenumber)))).*sumtot; %FFT of Gravity (Parker3D)

%Go Back to Time Domain
G=ifft2(FG,'symmetric');
%G=G';
[nz,mz]=size(Z_q);
G=G(1:nz,1:mz);
     end
     
             function Z_f=detrend_2d(app,Z)
        
        
        %REFERENCE:DETREND_2D= Munther (2021). flatten a data in 2D (https://www.mathworks.com/matlabcentral/fileexchange/33192-flatten-a-data-in-2d), MATLAB Central File Exchange. Retrieved June 25, 2021.
            % This function is written by Munther Gdeisat-The General Engineering
            % Research Institute (GERI) at Liverpool John Moores University.
            % This program is written on 9th October 2011
            % This function is the 2D equivalent of detrend function in Matlab
            % Z_f = DETREND(Z) removes the best plane fit trend from the data
            % in the 2D array Z and returns the residual in the 2D array Z_f
            if size(Z,2) < 2
                disp('Z must be a 2D array')
                return
            end 
            M = size(Z,2);
            N = size(Z,1);
            [X,Y] = meshgrid(1:M,1:N);
            %Make the 2D data as 1D vector
            Xcolv = X(:); % Make X a column vector
            Ycolv = Y(:); % Make Y a column vector
            Zcolv = Z(:); % Make Z a column vector
            Const = ones(size(Xcolv)); % Vector of ones for constant term
            % find the coeffcients of the best plane fit
            Coefficients = [Xcolv Ycolv Const]\Zcolv; % Find the coefficients
            XCoeff = Coefficients(1); % X coefficient
            YCoeff = Coefficients(2); % X coefficient
            CCoeff = Coefficients(3); % constant term
            % detrend the data
            Z_p = XCoeff * X + YCoeff * Y + CCoeff;
            Z_f = Z - Z_p;
            
             end
             
function [Xq_Utm,Yq_Utm,Zq_Utm,dx,dy]=UTM_Conv_km(app,DATA,UTM_Zone,UTM_hemisphere,Xsq,Ysq)

%DATA=load(INPUT);
%UTM_Zone=input('Choose UTM Zone (Only Number): ');
%During UTM hemisphere selection please write 'N' or 'S'
%UTM_hemisphere=input('Choose UTM Hemisphere (N or S): ');
[X_utm,Y_utm,UTM_Zone,UTM_hemisphere] = wgs2utm(app,DATA(:,2),DATA(:,1),UTM_Zone,UTM_hemisphere);

% %km conversion
% X_utm_km=X_utm./1000;
% Y_utm_km=Y_utm./1000;

%km is not working with Parker, therefore m is used.
X_utm_km=X_utm./1;
Y_utm_km=Y_utm./1;

%Processing time sigbificantly increased
% sorted_X_utm_km=sort(X_utm_km,'ascend');
% dx=sorted_X_utm_km(2)-sorted_X_utm_km(1);
% sorted_Y_utm_km=sort(Y_utm_km,'ascend');
% dy=sorted_Y_utm_km(2)-sorted_Y_utm_km(1);


%subtract min value to start km from 0
%X_utm_km=X_utm_km-min(X_utm_km);
%Y_utm_km=Y_utm_km-min(Y_utm_km);

%auto adjustment of sampling interval
[M,N]=size(Xsq);
 dx=(max(X_utm_km)-min(X_utm_km))./N;
[M,N]=size(Ysq);
 dy=(max(Y_utm_km)-min(Y_utm_km))./M;


%%
%MAPPING
[Xq_Utm,Yq_Utm]=meshgrid(min(X_utm_km):dx:max(X_utm_km), min(Y_utm_km):dy:max(Y_utm_km));
Zq_Utm=griddata(X_utm_km,Y_utm_km,DATA(:,3),Xq_Utm,Yq_Utm);


%filling NaN values along X-axis direction
%Zq_Utm = fillmissing(Zq_Utm,'linear','EndValues','nearest');
Zq_Utm = fillmissing(Zq_Utm,'movmedian',13); 
%if NaN values are remained across Y-axis direction
%Zq_Utm = fillmissing(Zq_Utm','linear','EndValues','nearest');
Zq_Utm = fillmissing(Zq_Utm','movmedian',13); 
Zq_Utm=Zq_Utm';

[MM,NN]=size(Zq_Utm);
for i=1:MM
    for j=1:NN
if isnan(Zq_Utm(i,j))==1
    Zq_Utm(i,j)=median(median(Zq_Utm,'omitnan'));
end
    end
end

figure;
h=pcolor(Xq_Utm,Yq_Utm,Zq_Utm);
shading interp
colormap jet
xlabel('UTM Easting (m)','FontSize',20,'FontName','Times New Roman')
ylabel('UTM Northing (m)','FontSize',20,'FontName','Times New Roman')
title('Cropped DATA UTM','FontSize',20)
end


function  [x,y,utmzone,utmhemi] = wgs2utm(app,Lat,Lon,utmzone,utmhemi)
% -------------------------------------------------------------------------
% [x,y,utmzone] = wgs2utm(Lat,Lon,Zone)
%
% Description:
%    Convert WGS84 coordinates (Latitude, Longitude) into UTM coordinates
%    (northing, easting) according to (optional) input UTM zone and
%    hemisphere.
%
% Input:
%    Lat: WGS84 Latitude scalar, vector or array in decimal degrees.  
%    Lon: WGS84 Longitude scalar, vector or array in decimal degrees. 
%    utmzone (optional): UTM longitudinal zone. Scalar or same size as Lat
%       and Lon.
%    utmhemi (optional): UTM hemisphere as a single character, 'N' or 'S',
%       or array of 'N' or 'S' characters of same size as Lat and Lon.
%
% Output:
%    x: UTM easting in meters.
%    y: UTM northing in meters.
%    utmzone: UTM longitudinal zone.
%    utmhemi: UTM hemisphere as array of 'N' or 'S' characters. 
%
% Author notes:
%    I downloaded and tried deg2utm.m from Rafael Palacios but found
%    differences of up to 1m with my reference converters in southern
%    hemisphere so I wrote my own code based on "Map Projections - A
%    Working Manual" by J.P. Snyder (1987). Quick quality control performed
%    only by comparing with LINZ converter
%    (www.linz.govt.nz/apps/coordinateconversions/) and Chuck Taylor's 
%    (http://home.hiwaay.net/~taylorc/toolbox/geography/geoutm.html) on a 
%    few test points, so use results with caution. Equations not suitable
%    for a latitude of +/- 90deg.
%
%    UPDATE: Following requests, this new version allows forcing UTM zone
%    in input.
%
% Examples:
%
%    % set random latitude and longitude arrays
%    Lat= 90.*(2.*rand(3)-1)
%    Lon= 180.*(2.*rand(3)-1)
%
%    % let the function find appropriate UTM zone and hemisphere from data 
%    [x1,y1,utmzone1,utmhemi1] = wgs2utm(Lat,Lon)
%
%    % forcing unique UTM zone and hemisphere for all data entries
%    % note: resulting easting and northing are way off the usual values
%    [x2,y2,utmzone2,utmhemi2] = wgs2utm(Lat,Lon,60,'S')
%
%    % forcing different UTM zone and hemisphere for each data entry
%    % note: resulting easting and northing are way off the usual values
%    utmzone = floor(59.*rand(3))+1
%    utmhemi = char(78 + 5.*round(rand(3)))
%    [x3,y3,utmzone3,utmhemi3] = wgs2utm(Lat,Lon,utmzone,utmhemi)
%
% Author: 
%   Alexandre Schimel
%   MetOcean Solutions Ltd
%   New Plymouth, New Zealand
%
% Version 2:
%   February 2011
%-------------------------------------------------------------------------
%% Argument checking
% if ~sum(double(nargin==[2,4]))
%     error('Wrong number of input arguments');return
% end
n1=size(Lat);
n2=size(Lon);
% if (n1~=n2)
%     error('Lat and Lon should have same size');return
% end
if exist('utmzone','var') && exist('utmhemi','var')
    n3=size(utmzone);
    n4=size(utmhemi);
    if (sort(n3)~=sort(n4))
        error('utmzone and utmhemi should have same size');return
    end
    if max(n3)~=1 && max(n3)~=max(n1)
        error('utmzone should have either same size as Lat and Long, or size=1');return
    end
end
% expand utmzone and utmhemi if needed
if exist('utmzone','var') && exist('utmhemi','var')
    n3=size(utmzone);
    n4=size(utmhemi);
    if n3==[1 1]
        utmzone = utmzone.*ones(size(Lat));
        utmhemi = char(utmhemi.*ones(size(Lat)));
    end
end
    
%% coordinates in radians
lat = Lat.*pi./180;
lon = Lon.*pi./180;
%% WGS84 parameters
a = 6378137;           %semi-major axis
b = 6356752.314245;    %semi-minor axis
% b = 6356752.314140;  %GRS80 value, originally used for WGS84 before refinements
e = sqrt(1-(b./a).^2); % eccentricity
%% UTM parameters
% lat0 = 0;                % reference latitude, not used here
if exist('utmzone','var')
    Lon0 = 6.*utmzone-183; % reference longitude in degrees
else
    Lon0 = floor(Lon./6).*6+3; % reference longitude in degrees
end
lon0 = Lon0.*pi./180;      % in radians
k0 = 0.9996;               % scale on central meridian
FE = 500000;              % false easting
if exist('utmhemi','var')
    FN = double(utmhemi=='S').*10000000;
else
    FN = (Lat < 0).*10000000; % false northing 
end
%% Equations parameters
eps = e.^2./(1-e.^2);  % e prime square
% N: radius of curvature of the earth perpendicular to meridian plane
% Also, distance from point to polar axis
N = a./sqrt(1-e.^2.*sin(lat).^2); 
T = tan(lat).^2;                
C = ((e.^2)./(1-e.^2)).*(cos(lat)).^2;
A = (lon-lon0).*cos(lat);                            
% M: true distance along the central meridian from the equator to lat
M = a.*(  ( 1 - e.^2./4 - 3.*e.^4./64 - 5.*e.^6./256 )  .* lat         ...
         -( 3.*e.^2./8 + 3.*e.^4./32 + 45.*e.^6./1024 ) .* sin(2.*lat) ...
         +( 15.*e.^4./256 + 45.*e.^6./1024 )            .* sin(4.*lat) ...
         -(35.*e.^6./3072 )                             .* sin(6.*lat) );
%% easting
x = FE + k0.*N.*(                                  A       ...
                 + (1-T+C)                      .* A.^3./6 ...
                 + (5-18.*T+T.^2+72.*C-58.*eps) .* A.^5./120 );
               
%% northing 
% M(lat0) = 0 so not used in following formula
y = FN + k0.*M + k0.*N.*tan(lat).*(                                     A.^2./2  ...
                                   + (5-T+9.*C+4.*C.^2)              .* A.^4./24 ...
                                   + (61-58.*T+T.^2+600.*C-330.*eps) .* A.^6./720 );
                                 
%% UTM zone
if exist('utmzone','var') && exist('utmhemi','var')
    utmzone = utmzone;
    utmhemi = utmhemi;
else 
   utmzone = floor(Lon0./6)+31;
   utmhemi = char( 83.* (Lat < 0) + 78.* (Lat >= 0) );
end
end

        function [x,y,z] = grid2xyz(app,Xgrid, Ygrid, Zgrid)
            %GRID2XYZ converts a grid to xyz and removes NaN's
%
%   Syntax:
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   Input:
%   Xgrid = x grid coordinates
%   Ygrid = y grid coordinates
%   Zgrid = z grid coordinates
%
%   Output:
%   x = x coordinate (vector)
%   y = y coordinate (vector)
%   z = z coordinate (vector)
%
%   Example
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   See also 

%% Copyright notice
%   --------------------------------------------------------------------
%   Copyright (C) 2010 Alkyon Hydraulic Consultancy & Research
%       grasmeijerb
%
%       bart.grasmeijer@alkyon.nl	
%
%       P.O. Box 248
%       8300 AE Emmeloord
%       The Netherlands
%
%   This library is free software: you can redistribute it and/or
%   modify it under the terms of the GNU Lesser General Public
%   License as published by the Free Software Foundation, either
%   version 2.1 of the License, or (at your option) any later version.
%
%   This library is distributed in the hope that it will be useful,
%   but WITHOUT ANY WARRANTY; without even the implied warranty of
%   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
%   Lesser General Public License for more details.
%
%   You should have received a copy of the GNU Lesser General Public
%   License along with this library. If not, see <http://www.gnu.org/licenses/>.
%   --------------------------------------------------------------------


%%

x = reshape(Xgrid,[numel(Xgrid),1]);
y = reshape(Ygrid,[numel(Ygrid),1]);
z = reshape(Zgrid,[numel(Zgrid),1]);
mynans = isnan(x) | isnan(y) | isnan(z);
x(mynans) = [];
y(mynans) = [];
z(mynans) = [];
        end
        
                 function [dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA1,DATA2,m,n)
%INPUT
%DATA1, DATA2: Input data
%m,n=gridsize in x and y directions
%OUTPUT
%dx1,dx2=new x spacing for DATA1 and DATA2
%dy1,dy2=new y spacing for DATA1 and DATA2
%If this spacing is used, the desired gridsize will be obtained or
%approximated +-1

 %DATA1
 x1=linspace(min(DATA1(:,1)),max(DATA1(:,1)),n);x1=x1';dx1=x1(2)-x1(1);
 y1=linspace(min(DATA1(:,2)),max(DATA1(:,2)),m);y1=y1';dy1=y1(2)-y1(1);
 %DATA2
 x2=linspace(min(DATA2(:,1)),max(DATA2(:,1)),n);x2=x2';dx2=x2(2)-x2(1);
 y2=linspace(min(DATA2(:,2)),max(DATA2(:,2)),m);y2=y2';dy2=y2(2)-y2(1);
 
end
     
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function runbutton(app, event)
           
            dx=str2double(app.dxmEditField.Value); %defining dx and dy       
            dy=str2double(app.dymEditField.Value);           
            drho=str2double(app.drhokgm3EditField.Value);
              
                        
            if app.Autoz0CheckBox.Value==true;
                Auto_depth_calc=1;
            else
                Auto_depth_calc=0;
               z0=str2double(app.z0mEditField.Value); 
            end
                
            n=str2double(app.nEditField.Value);


             if app.TrendRemovalCheckBox.Value==true;
                 TREND=1;
             else
                 TREND=0;
             end
%%

%FWG PARKER
%%

%Use negative topography for marine!!!

%INPUT
DATA=load(app.FILE1);


%Define X,Y,Z
X=DATA(:,1);
Y=DATA(:,2);
Z=DATA(:,3);
%%
%gridding with gridding_mapping func.
[Xq,Yq,Zq,Z_q]=gridding_mapping(app,X,Y,Z,dx,dy);
title('Input Layer Grid','FontSize',20,'FontName','Times New Roman')
%%
%PARKER 3D
if Auto_depth_calc==1;
    z0=mean(Z);
else
    z0=z0;
end
[G]=parker3D(app,Z_q,n,dx,dy,drho,z0);
%%
%DETREND 2D (OPTIONAL)
if TREND==1
G=detrend_2d(app,G); %Removing trend (Optional)
else
G=G;
end
%%
%MAPPING
figure;
h=pcolor(Xq,Yq,G);
set(h, 'EdgeColor', 'none');
shading interp
colormap jet
%xlabel('Longitude','FontSize',20,'FontName','Times New Roman')
%ylabel('Latitude','FontSize',20,'FontName','Times New Roman')
colorbar;
set(gca,'FontSize',12,'Fontname','times')
title('Gravity Anomaly of Input Layer Grid','FontSize',20,'FontName','Times New Roman')

[x,y,z] = grid2xyz(app,Xq, Yq, G);

%% OUTPUT

               fid=fopen('fwg_parker3D.dat','w');
               %fprintf(fid,'%18s %18s %18s\n','UTM_X(m)','UTM_Y(m)','Z(mgal)');               
              for i=1:length(x)
                  WR2=[x(i),y(i),z(i)];
              fprintf(fid,'%18f %18f %18f\n', WR2);
              end
              fclose(fid);
             
            
        end

        % Button pushed function: BrowseButton
        function browse(app, event)
             [file,path]=uigetfile('*.dat');
            
            if isequal(file,0)
                f=errordlg('You have not selected a dat file','Warning!')
            else
                %disp(['User selected', fullfile(path,file)]);
                app.LayerEditField.Value=file;
                app.FILE1=fullfile(path,file);
                app.Lamp.Color = [0 1 0];
            end         
        end

        % Button pushed function: WavenumberEqualizationButton
        function wave_eq(app, event)
            w_equalization
        end

        % Button pushed function: UTMConversionButton
        function UTM_Convert(app, event)
         UTM_Conv
        end

        % Button pushed function: CheckButton
        function check(app, event)
            dx=str2double(app.dxmEditField.Value); %defining dx and dy
            %f=errordlg(num2str(dxx),'It will work') %Shows It will work or not
            if isnan(dx) %If NaN is given for dx show error dialog
            f=errordlg('dx is empty','Warning!')
            else
            app.Lamp_2.Color = [0 1 0];   
            end
            
            dy=str2double(app.dymEditField.Value);
            %f=errordlg(num2str(dyy),'It will work')  %Shows It will work or not
            if isnan(dy) %If NaN is given for dy show error dialog
            f=errordlg('dy is empty','Warning!')
             else
            app.Lamp_3.Color = [0 1 0];  
            end    
            
            drho=str2double(app.drhokgm3EditField.Value);
            %f=errordlg(num2str(dyy),'It will work')  %Shows It will work or not
            if isnan(drho) %If NaN is given for dy show error dialog
            f=errordlg('drho is empty','Warning!')
             else
            app.Lamp_5.Color = [0 1 0];     
            end       
            
            n=str2double(app.nEditField.Value);
            %f=errordlg(num2str(dyy),'It will work')  %Shows It will work or not
            if isnan(n) %If NaN is given for dy show error dialog
            f=errordlg('n is empty','Warning!')
             else
           app.Lamp_6.Color = [0 1 0]; 
            end


            sumappLampColor=app.Lamp_2.Color+app.Lamp_3.Color+app.Lamp_5.Color+app.Lamp_6.Color;
            
            if sumappLampColor==[0 4 0]
            app.StatusTextArea.Value = {'Prepare yourself to press "RUN"'};
            else
            app.StatusTextArea.Value = {'Opps! Something is missing'};
            end
            
        end

        % Button pushed function: RestartButton
        function restart(app, event)
            app.Lamp_3.Color = [1 0 0];
            app.Lamp.Color = [1 0 0];
            app.Lamp_2.Color = [1 0 0];
            app.Lamp_6.Color = [1 0 0];
            app.Lamp_5.Color = [1 0 0];
            
            app.LayerEditField.Value = ' ';
            app.dxmEditField.Value = ' ';
            app.dymEditField.Value=' ';
            app.z0mEditField.Value=' ';
            app.drhokgm3EditField.Value='1670';
            app.nEditField.Value='4';
            app.StatusTextArea.Value = {'Please Press Check!'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LayerEditFieldLabel = uilabel(app.UIFigure);
            app.LayerEditFieldLabel.Text = 'Layer';
            app.LayerEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.WarningTextArea = uitextarea(app.UIFigure);
            app.WarningTextArea.Value = {'o Selecting extremely small grid may oveload the memory.'; 'o Selecting extremely large grid spacing producess low-resolution image.'; 'o Please optimise dx and dy selection.'; 'o z0 should be negative for marine and positive for land data'; 'o Please use negative values for marine and positive values for land. Do not use positive marine data as an input.'; 'o Trend removal is optional and it is not recommended for layer stripping process.'; 'o Default value for drho is 1670 kg/m3. This value is obtain from 2700 (assumed basement density)-1030 (assumed water column density).'; 'o n is degree of expansion for Parker''s equation. Default value is 4'; 'o If "Auto z0" is selected, you do not have to type "z0" value '; 'o "Wavenumber Equalization" button is optional. However, it is recommended for layer stripping process.'; 'o Please use "Wavenumber Equalization" button after "RUN!" button. '};
            app.dxmEditFieldLabel = uilabel(app.UIFigure);
            app.dxmEditFieldLabel.Text = 'dx (m)';
            app.dxmEditField = uieditfield(app.UIFigure, 'text');
            app.dymEditFieldLabel = uilabel(app.UIFigure);
            app.dymEditFieldLabel.Text = 'dy (m)';
            app.dymEditField = uieditfield(app.UIFigure, 'text');
            app.z0mEditFieldLabel = uilabel(app.UIFigure);
            app.z0mEditFieldLabel.Text = 'z0 (m)';
            app.z0mEditField = uieditfield(app.UIFigure, 'text');
            app.drhokgm3EditFieldLabel = uilabel(app.UIFigure);
            app.drhokgm3EditFieldLabel.Text = 'drho (kg/m3)';
            app.drhokgm3EditField = uieditfield(app.UIFigure, 'text');
            app.drhokgm3EditField.Value = '1670';
            app.TrendRemovalCheckBox = uicheckbox(app.UIFigure);
            app.TrendRemovalCheckBox.Text = 'Trend Removal';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @runbutton, true);
            app.RUNButton.Text = {'RUN!'; ''};
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'text');
            app.nEditField.Value = '4';
            app.Autoz0CheckBox = uicheckbox(app.UIFigure);
            app.Autoz0CheckBox.Text = 'Auto z0';
            app.Autoz0CheckBox.Value = true;
            app.WavenumberEqualizationButton = uibutton(app.UIFigure, 'push');
            app.WavenumberEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @wave_eq, true);
            app.WavenumberEqualizationButton.Text = {'Wavenumber'; 'Equalization'};
            app.UTMConversionButton = uibutton(app.UIFigure, 'push');
            app.UTMConversionButton.ButtonPushedFcn = createCallbackFcn(app, @UTM_Convert, true);
            app.UTMConversionButton.Text = 'UTM Conversion';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @check, true);
            app.CheckButton.Text = 'Check';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @restart, true);
            app.RestartButton.Text = 'Restart';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Value = {'Please Press Check!'};
            app.OperationsMenuLabel = uilabel(app.UIFigure);
            app.OperationsMenuLabel.Text = 'Operations Menu';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fwg_parker3D_v5mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end