classdef w_equalization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseButton                matlab.ui.control.Button
        FAAEditFieldLabel           matlab.ui.control.Label
        FAAEditField                matlab.ui.control.EditField
        BrowseButton_2              matlab.ui.control.Button
        LayerGravityEditFieldLabel  matlab.ui.control.Label
        LayerGravityEditField       matlab.ui.control.EditField
        GridsizePanel               matlab.ui.container.Panel
        mEditFieldLabel             matlab.ui.control.Label
        mEditField                  matlab.ui.control.EditField
        nEditFieldLabel             matlab.ui.control.Label
        nEditField                  matlab.ui.control.EditField
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        degreeEditFieldLabel        matlab.ui.control.Label
        degreeEditField             matlab.ui.control.EditField
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        RunButton                   matlab.ui.control.Button
        CheckButton                 matlab.ui.control.Button
        StatusTextAreaLabel         matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        RestartButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
                 %Go top left toolbar-->Property-->Public
        FILE1 %assign the selected file to FILE1
        FILE2 %assign the selected file to FILE2
    end
    
    
     methods (Access = public)
         function [xq_filt,yq_filt,Filtered] = butterworth_lp_2D(app,Xq,Yq,Zq,degree,c_off)

%Arisoy M.Ö. and Dikmen Ü., 2011. Potensoft: MATLAB-based software for
%potential field data processing, modelling and mapping.

%[Zq xmin xmax ymin ymax]=grd_read_v2('text_fwg_parker3D_cropped_eastmed_FAA.grd');

%INPUT
Ffwgparker3D = raps(app,Zq);
% c_off=0.25;
% degree=4;

%2D Filtering Process
%Wavenumber Design
% fnyq=max(Ffwgparker3D(:,1));
% gridsize=size(Zq);
% nmax=max(gridsize);
% npts = 2^nextpow2(nmax);
% nx = gridsize(1);
% ny = gridsize(2);
% xf = linspace(-fnyq,fnyq,npts);
% yf = linspace(-fnyq,fnyq,npts);
% [f1,f2] = meshgrid(xf,yf);
%%
%Wavenumber Design2 / grid_squarization
[Zq] = grid_squarization(app,Zq);
[yq_filt] = grid_squarization(app,Yq);
[xq_filt] = grid_squarization(app,Xq);

%Squared size will be used for further process
[N, M] = size(Zq);
            
wx=fcoef1(app,N);
wx=wx(1:floor(N/2)+1);
f1=wx';

wy=fcoef1(app,M);
wy=wy(1:floor(M/2)+1);
f2=wy';


%%
nmax=max(N,M);
npts = 2^nextpow2(nmax);
nx = N; ny = M;
xf = linspace(-max(f1),max(f1),npts);
yf = linspace(-max(f2),max(f2),npts);
[f1,f2] = meshgrid(xf,yf);

%%
r = sqrt(f1.^2 + f2.^2);  
Xpos = c_off;     
filter = 1./(1+(r./(Xpos)).^degree);
%Expanding data for FFT process
cdiff=floor((npts-ny)/2); rdiff=floor((npts-nx)/2);
% Prep_data=Zq-mean(Zq(:)); detrend
%Zero Padding
Prep_data=Zq;
[Npad,Mpad]=size(Prep_data);
u=isnan(Prep_data);
for i=1:Npad
    for j=1:Mpad
if u(i,j)==1
   Prep_data(i,j)=0;
end
    end
end

%%%%%%%
Prep_data1=taper2d(app,Prep_data,npts,nx,ny,rdiff,cdiff); %Hinders from data shifts and loss withing the F domain.
%FFT
F_Prep_data1=fftshift(fft2(Prep_data1));


%%
%Filtering Operation
F_Filtered = F_Prep_data1.*filter;
Filtered=ifft2(fftshift(F_Filtered));
Filtered=real(Filtered(1+rdiff:nx+rdiff,1+cdiff:ny+cdiff));

         end
         
%          function [cc] = cross_correlation(app,F_FAAA,F_layer_FAA)
% 
% %Cross correlation between topography and FAA
% for j=1:length(F_layer_FAA)
% 
% for i=1:length(F_FAAA)
%         F_FAA_dev= F_layer_FAA(i)- mean(F_layer_FAA);
%         F_Topo_dev= F_FAAA(i) - mean(F_FAAA);
%         numerator(i) = F_FAA_dev.*F_Topo_dev;
% end
%     
%     for i=1:length(F_layer_FAA);
%         F_FAA_dev_den(i)= (F_layer_FAA(i)- mean(F_layer_FAA))^2;
%         F_Topo_dev_den(i)= (F_FAAA(i) - mean(F_FAAA))^2;
%     end
%     
%     aa= sum(F_FAA_dev_den);
%     bb= sum(F_Topo_dev_den);
%     
%     nume = sum(numerator);
%     Denum = sqrt(aa*bb);
%     
%     one_cc(j) = nume/Denum;
% end
% 
% cc=numerator/Denum; cc=cc';
% subplot(4,1,3)
% plot(cc)
% title('XCORR FAA and TOPO')
% xlabel('Index')
% ylabel('Correlation Coefficient')
% set(gca,'Fontname','times','FontSize',12)
%          end
         
         function [dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA1,DATA2,m,n)
%INPUT
%DATA1, DATA2: Input data
%m,n=gridsize in x and y directions
%OUTPUT
%dx1,dx2=new x spacing for DATA1 and DATA2
%dy1,dy2=new y spacing for DATA1 and DATA2
%If this spacing is used, the desired gridsize will be obtained or
%approximated +-1

 %DATA1
 x1=linspace(min(DATA1(:,1)),max(DATA1(:,1)),n);x1=x1';dx1=x1(2)-x1(1);
 y1=linspace(min(DATA1(:,2)),max(DATA1(:,2)),m);y1=y1';dy1=y1(2)-y1(1);
 %DATA2
 x2=linspace(min(DATA2(:,1)),max(DATA2(:,1)),n);x2=x2';dx2=x2(2)-x2(1);
 y2=linspace(min(DATA2(:,2)),max(DATA2(:,2)),m);y2=y2';dy2=y2(2)-y2(1);
 
end

function w = fcoef1(app,n)

%REFERENCE:Ridsdill-Smith, T. A. (2000). The application of the wavelet transform to the processing of aeromagnetic data (p. 197). Perth: University of Western Australia.

            % function [w]=fcoef1(n)
            % Calculates the 1D Fourier angular frequencies
            % for a signal of length n
            % in (1/K_unit)
            % % Tom Ridsdill-Smith   March 2000
            %
            if (rem(n,2)==0)
               % Even signal length
               w=[(0:(n/2)) (((-n/2)+1):-1)].*(1/n);
            else
               % Odd signal length
               w=[(0:((n-1)/2)) ((-(n-1)/2):-1)].*(1/(n-1));
   
            end
        end
      
        function [x,y,z] = grid2xyz(app,Xgrid, Ygrid, Zgrid)
%GRID2XYZ converts a grid to xyz and removes NaN's
%
%   Syntax:
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   Input:
%   Xgrid = x grid coordinates
%   Ygrid = y grid coordinates
%   Zgrid = z grid coordinates
%
%   Output:
%   x = x coordinate (vector)
%   y = y coordinate (vector)
%   z = z coordinate (vector)
%
%   Example
%   [x,y,z] = grid2xyz(Xgrid, Ygrid, Zgrid)
%
%   See also 

%% Copyright notice
%   --------------------------------------------------------------------
%   Copyright (C) 2010 Alkyon Hydraulic Consultancy & Research
%       grasmeijerb
%
%       bart.grasmeijer@alkyon.nl	
%
%       P.O. Box 248
%       8300 AE Emmeloord
%       The Netherlands
%
%   This library is free software: you can redistribute it and/or
%   modify it under the terms of the GNU Lesser General Public
%   License as published by the Free Software Foundation, either
%   version 2.1 of the License, or (at your option) any later version.
%
%   This library is distributed in the hope that it will be useful,
%   but WITHOUT ANY WARRANTY; without even the implied warranty of
%   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
%   Lesser General Public License for more details.
%
%   You should have received a copy of the GNU Lesser General Public
%   License along with this library. If not, see <http://www.gnu.org/licenses/>.
%   --------------------------------------------------------------------

% This tool is part of <a href="http://OpenEarth.nl">OpenEarthTools</a>.
% OpenEarthTools is an online collaboration to share and manage data and 
% programming tools in an open source, version controlled environment.
% Sign up to recieve regular updates of this function, and to contribute 
% your own tools.

%% Version <http://svnbook.red-bean.com/en/1.5/svn.advanced.props.special.keywords.html>
% Created: 05 Jul 2010
% Created with Matlab version: 7.10.0.499 (R2010a)

% $Id$
% $Date$
% $Author$
% $Revision$
% $HeadURL$
% $Keywords: $

%%

x = reshape(Xgrid,[numel(Xgrid),1]);
y = reshape(Ygrid,[numel(Ygrid),1]);
z = reshape(Zgrid,[numel(Zgrid),1]);
mynans = isnan(x) | isnan(y) | isnan(z);
x(mynans) = [];
y(mynans) = [];
z(mynans) = [];
        end
        
        function [Zq] = grid_squarization(app,Zq)
%Create Square Grid from rectangular grid
%INPUT
%Zq=rectangular grid
%OUTPUT
%Zq=square grid

[N, M] = size(Zq);

            %Zqf = fftshift(fft2(Zq));
            %Zqfp = (abs(Zq)).^2;
            sizediff = abs(N-M);
            % Make square
            if N > M                                                                    % More rows than columns
                if ~mod(sizediff,2)                                                      % Even difference
                    Zq = [NaN(N,sizediff/2) Zq NaN(N,sizediff/2)];                  % Pad columns to match dimensions
                else                                                                    % Odd difference
                    Zq = [NaN(N,floor(sizediff/2)) Zq NaN(N,floor(sizediff/2)+1)];
                end
            elseif N < M                                                                % More columns than rows
                if ~mod(sizediff,2)                                                      % Even difference
                    Zq = [NaN(sizediff/2,M); Zq; NaN(sizediff/2,M)];                % Pad rows to match dimensions
                else
                    Zq = [NaN(floor(sizediff/2),M); Zq; NaN(floor(sizediff/2)+1,M)];% Pad rows to match dimensions
                end
            end
end

function [Xq,Yq,Zq,Z_q]=gridding_mapping(app,X,Y,Z,dx,dy)

%NOTE
% %Grid X and Y coordinates
% %Obtain desired n and m for gridsize by this code:
% [n,m]=size(Filtered);
% xx=linspace(xmin2,xmax2,m);xx=xx';dxx=xx(2)-xx(1);
% yy=linspace(ymin2,ymax2,n);yy=yy';dyy=yy(2)-yy(1);
% [xq,yq]=meshgrid(min(xx):dxx:max(xx),min(yy):dyy:max(yy));


%gridding
[Xq,Yq]=meshgrid(min(X):dx:max(X), min(Y):dy:max(Y));
Zq=griddata(X,Y,Z,Xq,Yq);

%filling NaN values along X-axis direction
Zq = fillmissing(Zq,'linear','EndValues','nearest');
%if NaN values are remained across Y-axis direction
Zq = fillmissing(Zq','linear','EndValues','nearest');
Z_q=Zq';

%% detrend_2d
%Z_q=detrend_2d(Zq);

%map
figure;
h=pcolor(Xq,Yq,Z_q);
set(h, 'EdgeColor', 'none');
shading interp
colormap jet
%xlabel('Longitude','FontSize',20,'FontName','Times New Roman')
%ylabel('Latitude','FontSize',20,'FontName','Times New Roman')
colorbar;
set(gca,'FontSize',12,'Fontname','times')
end

function cv3 = raps(app,img)
            % function raps(img)
            % Computes and plots radially a power spectrum of magnetic data in
            % a GEOtif image of the amplitde spectrum
            % Based and modified on (C) E. Ruzanski, RCG, 2009 and 
            % Tom Ridsdill-Smith  March 2000
            %% Process image size information
            [N, M] = size(img);
            %% Compute spectrum
            imgf = fftshift(fft2(img));
            imgfp = (abs(imgf)).^2;
            %% Adjust spectrum size
            dimDiff = abs(N-M);
            dimMax = max(N,M);
            % Make square
            if N > M                                                                    % More rows than columns
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(N,dimDiff/2) imgfp NaN(N,dimDiff/2)];                  % Pad columns to match dimensions
                else                                                                    % Odd difference
                    imgfp = [NaN(N,floor(dimDiff/2)) imgfp NaN(N,floor(dimDiff/2)+1)];
                end
            elseif N < M                                                                % More columns than rows
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(dimDiff/2,M); imgfp; NaN(dimDiff/2,M)];                % Pad rows to match dimensions
                else
                    imgfp = [NaN(floor(dimDiff/2),M); imgfp; NaN(floor(dimDiff/2)+1,M)];% Pad rows to match dimensions
                end
            end
            %% Compute radially average amplitude spectrum
            [X, Y] = meshgrid(-dimMax/2:dimMax/2-1, -dimMax/2:dimMax/2-1);               % Make Cartesian grid
            [~, rho] = cart2pol(X, Y);                                               % Convert to polar coordinate axes
            rho = round(rho);
            i = cell(floor(dimMax/2) + 1, 1);
            for r = 0:floor(dimMax/2)
                i{r + 1} = find(rho == r);
            end
            Pf = zeros(1, floor(dimMax/2)+1);
            CF = zeros(1, floor(dimMax/2)+1);
            for r = 0:floor(dimMax/2)
                Pf(1, r + 1) = nanmean( log( sqrt( imgfp( i{r+1} ) ) ) ); %amplitde spectra
                CF(1, r + 1) = 1.96 * ( nanstd( log( sqrt( imgfp( i{r+1} ) ) ) ) ...
                    / sqrt( length( sqrt( imgfp( i{r+1} ) ) ) ) ); %confidence interval
            end
            %% calculate the 1D angular frequencies
            % for a signal of length n
            % using the fcoef fuction
            w=fcoef1(app,dimMax);
            w=w(1:floor(dimMax/2)+1);
            w1=w';
            Pf2=Pf';
            CF2=CF';
            cv2=[w1(2:size(w1)),Pf2(2:size(Pf2)),CF2(2:size(CF2))];
            [cv3]=cv2(cv2(:,3) <= 1, :);
end

function [Cxy,w,x2,y2] = spec_cohe(app,F_Topo,F_FAA)

%Spectral Coherency between Fourier Transform of FAA and Topography
%-----------------------------
%INPUT
%F_Topo=Fourier transform of Topography
%F_FAA=Fourier transform of FAA
%OUTPUT
%Cxy=spectral coherency between inputs
%w=Normalised wavenumber. w./2pi provides the wavenumber

[Cxy,w]=mscohere(F_Topo,F_FAA);
subplot(3,1,3)
plot(w./(2.*pi),Cxy,'g')
title('Cross-Coherence')
xlabel('Wavenumber')
ylabel('Magnitude Squared Coherence')
set(gca,'Fontname','times','FontSize',12)
[x2,y2]=ginput(1);
hold on
plot([x2 x2],[min(Cxy) max(Cxy)],'b','linewidth',2)
end

function gt=taper2d(app,g,npts,nr,nc,rdiff,cdiff)
% Merge edges to the value opposite using a cubic spline

%Arisoy M.Ö. and Dikmen Ü., 2011. Potensoft: MATLAB-based software for
%potential field data processing, modelling and mapping.


gt=zeros(npts); gt(rdiff+1:rdiff+nr,cdiff+1:cdiff+nc)=g;
gp=g(:,nc-2:nc); [gpx2,junk]=gradient(gp);[gpx1,junk]=gradient(gp);
x1=0; x2=(2*cdiff)+1;
x=[1 1 0 0;x1 x2 1 1; x1^2 x2^2 2*x1 2*x2; x1^3 x2^3 3*x1^2 3*x2^2]; 
for I=1:nr;
 y=[g(I,nc) g(I,1) gpx2(I,3) gpx1(I,1)];
 c=y/x;
 for J=1:cdiff;
  gt(I+rdiff,J)=c(1)+(J+cdiff)*c(2)+c(3)*(J+cdiff)^2+c(4)*(J+cdiff)^3;
  gt(I+rdiff,J+nc+cdiff)=c(1)+J*c(2)+c(3)*J^2+c(4)*J^3;
 end;
end;

gp=g(1:3,:);     [junk,gpx1]=gradient(gp);  % top and bottom
gp=g(nr-2:nr,:); [junk,gpx2]=gradient(gp);
x1=0; x2=(2*rdiff)+1;
x=[1 1 0 0;x1 x2 1 1; x1^2 x2^2 2*x1 2*x2; x1^3 x2^3 3*x1^2 3*x2^2]; 
for J=1:nc;
 y=[g(nr,J) g(1,J) gpx2(3,J) gpx1(1,J)];
 c=y/x;
 for I=1:rdiff;
  gt(I,J+cdiff)=c(1)+(I+rdiff)*c(2)+c(3)*(I+rdiff)^2+c(4)*(I+rdiff)^3;
  gt(I+rdiff+nr,J+cdiff)=c(1)+I*c(2)+c(3)*I^2+c(4)*I^3;
 end;
end;

for I=rdiff+nr+1:npts; % Corners
 for J=cdiff+nc+1:npts;
  if (I-nr-rdiff)>(J-nc-cdiff); gt(I,J)=gt(I,nc+cdiff); else gt(I,J)=gt(nr+rdiff,J); end;
 end;
end;

for I=1:rdiff; 
 for J=1:cdiff;
  if I>J; gt(I,J)=gt(rdiff+1,J); else gt(I,J)=gt(I,cdiff+1); end;
 end;
end;

for I=1:rdiff; % bottom right
 for J=cdiff+nc+1:npts;
  if I>(npts-J); gt(I,J)=gt(rdiff+1,J); else gt(I,J)=gt(I,cdiff+nc); end;
 end;
end;

for I=rdiff+nr+1:npts; % top left
 for J=1:cdiff;
  if (npts-I)>J; gt(I,J)=gt(rdiff+nr,J); else gt(I,J)=gt(I,cdiff+1); end;
 end;
end;       
end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function browse1(app, event)
            [file,path]=uigetfile('*.dat');
            
            if isequal(file,0)
                f=errordlg('You have not selected a dat file','Warning!')
            else
                %disp(['User selected', fullfile(path,file)]);
                app.FAAEditField.Value=file;
                app.FILE1=fullfile(path,file);
                app.Lamp.Color = [0 1 0];
                
            end
        end

        % Button pushed function: BrowseButton_2
        function browse2(app, event)
            [file2,path2]=uigetfile('*.dat');
            if isequal(file2,0)
                f=errordlg('You have not selected a dat file','Warning!') %
            else
                %disp(['User selected', fullfile(path,file)]);
                app.LayerGravityEditField.Value=file2;
                app.FILE2=fullfile(path2,file2); %
                app.Lamp_2.Color = [0 1 0];
                
            end    
        end

        % Button pushed function: RunButton
        function runbutton(app, event)
%Define input data
m=str2double(app.mEditField.Value); %defining m and n  
n=str2double(app.nEditField.Value); %defining m and n
degree=str2double(app.degreeEditField.Value); %defining degree
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %INPUT
DATA1=load(app.FILE1);
DATA2=load(app.FILE2);
% DATA1=load('text_med1_Cropped_FAA_akdeniz.dat');
% DATA2=load('text_fwg_parker3D_cropped_eastmed_FAA.dat');
% degree=80;
% %%
% %Please use square grids
% 
% n=250; %Grid size n
% m=250; %Grid size m
%%
%Generate desired m*n grid / desired_gridsize function
% %Obtain desired n and m for gridsize by this code:
[dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA1,DATA2,m,n);

%%
%Gridding_mapping / gridding_mapping function
[xq1,yq1,Zq1,matrix1]=gridding_mapping(app,DATA1(:,1),DATA1(:,2),DATA1(:,3),dx1,dy1);
[xq2,yq2,Zq2,matrix2]=gridding_mapping(app,DATA2(:,1),DATA2(:,2),DATA2(:,3),dx2,dy2);
close all;
%%
%make square grid / grid_squarization function
% xq=grid_squarization(xq);
% yq=grid_squarization(yq);
% matrix1=grid_squarization(matrix1);
% matrix2=grid_squarization(matrix2);

%%
%RAPS / RAPS function
F_FAA = raps(app,matrix1);
Ffwgparker3D = raps(app,matrix2);


%PLOT RAPS
figure;
subplot(3,1,1)
plot(F_FAA(:,1),F_FAA(:,2),'r')
title('RAPS of FAA')
xlabel('Wavenumber')
ylabel('ln[A(k)]')
set(gca,'Fontname','times','FontSize',12)
subplot(3,1,2)
plot(Ffwgparker3D(:,1),Ffwgparker3D(:,2),'k')
title('RAPS of fw modelled layer')
xlabel('Wavenumber')
ylabel('ln[A(k)]')
set(gca,'Fontname','times','FontSize',12)


%%
%Interpolate FAA for XCORR and coherency
x_fwgparker3D_q=linspace(min(Ffwgparker3D(:,1)),max(Ffwgparker3D(:,1)),length(F_FAA(:,2))); 
y_fwgparker3D_q=interp1(Ffwgparker3D(:,1),Ffwgparker3D(:,2),x_fwgparker3D_q); y_fwgparker3D_q=y_fwgparker3D_q'; x_fwgparker3D_q=x_fwgparker3D_q';
F_FAAA=F_FAA(:,2);
F_layer_FAA=y_fwgparker3D_q;
%%
%XCORR / cross_correlation function
%Cross correlation between topography and FAA
%[cc]=cross_correlation(app,F_FAAA,F_layer_FAA);

%%
%CROSS SPECTRUM COHERENCY / spec_cohe function
[Cxy,w,x2,y2] = spec_cohe(app,F_FAA(:,2),y_fwgparker3D_q);
w=w./2.*pi; %Convert from normalised wavenumber to wavenumber

%%
%LP BW Filtering / butterworth_lp_2D function
[xq_filt,yq_filt,Filtered]=butterworth_lp_2D(app,xq2,yq2,matrix2,degree,x2);


%Regrid Filtered matrix /grid2xyz function
[x22,y22,z22] = grid2xyz(app,xq_filt, yq_filt, Filtered);
DATA3=[x22,y22,z22];
[dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA3,DATA3,m,n);
[xq,yq,Zq,Filtered]=gridding_mapping(app,DATA3(:,1),DATA3(:,2),DATA3(:,3),dx2,dy2);
close;
[x22,y22,z22] = grid2xyz(app,xq, yq, Filtered);
%Regrid Original layer'sFAA to compare Filtered layer's FAA and make them same size
[x2,y2,z2] = grid2xyz(app,xq2, yq2, matrix2);
DATA4=[x2,y2,z2];
[dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA4,DATA4,m,n);
[xq2,yq2,Zq2,matrix2]=gridding_mapping(app,DATA4(:,1),DATA4(:,2),DATA4(:,3),dx2,dy2);
close;
[x2,y2,z2] = grid2xyz(app,xq2, yq2, matrix2);
%Regrid FAA for layer stripping process
[x1,y1,z1] = grid2xyz(app,xq1, yq1, matrix1);
DATA5=[x1,y1,z1];
[dx1,dy1,dx2,dy2] = desired_gridsize(app,DATA5,DATA4,m,n);
[xq1,yq1,Zq1,matrix1]=gridding_mapping(app,DATA5(:,1),DATA5(:,2),DATA5(:,3),dx1,dy1);
close;
[x1,y1,z1] = grid2xyz(app,xq1, yq1, matrix1);
%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%PLOT ORIGINAL AND FILTERED DATA
figure;
subplot(2,1,1); pcolor(xq,yq,matrix2);shading interp;colormap jet;
title('Original fw modelled layer')
colorbar;
set(gca,'Fontname','times','FontSize',12)
subplot(2,1,2); pcolor(xq,yq,Filtered);shading interp;colormap jet;
title('Filtered fw modelled layer')
colorbar;
set(gca,'Fontname','times','FontSize',12)

%%
%COMPARE RAPS AND FILTERED RAPS
figure;
subplot(3,1,1)
plot(Ffwgparker3D(:,1),Ffwgparker3D(:,2),'k')
title('RAPS of fw modelled layer')
xlabel('Wavenumber')
ylabel('ln[A(k)]')
axis([min(Ffwgparker3D(:,1)) max(Ffwgparker3D(:,1)) 0 max(Ffwgparker3D(:,2))+5])
set(gca,'Fontname','times','FontSize',12)

Filtered_Ffwgparker3D = raps(app,Filtered);
subplot(3,1,2)
plot(Filtered_Ffwgparker3D(:,1),Filtered_Ffwgparker3D(:,2),'k')
title('Filtered RAPS of fw modelled layer')
xlabel('Wavenumber')
ylabel('ln[A(k)]')
axis([min(Ffwgparker3D(:,1)) max(Ffwgparker3D(:,1)) 0 max(Ffwgparker3D(:,2))+5])
set(gca,'Fontname','times','FontSize',12)


subplot(3,1,3)
plot(F_FAA(:,1),F_FAA(:,2),'r')
title('RAPS of FAA')
xlabel('Wavenumber')
ylabel('ln[A(k)]')
axis([min(Ffwgparker3D(:,1)) max(Ffwgparker3D(:,1)) 0 max(Ffwgparker3D(:,2))+5])
set(gca,'Fontname','times','FontSize',12)       


              fid=fopen('FAA.dat','w');
               %fprintf(fid,'%18s %18s %18s %18s %18s\n','UTM_X(m)','UTM_Y(m)','Z(mgal)', 'Longitude', 'Latitude');               
              for i=1:length(x1)
                  WR1=[x1(i),y1(i),z1(i)];
              fprintf(fid,'%18f %18f %18f\n', WR1);
              end
              fclose(fid);
              
               fid=fopen('Layer_Gravity.dat','w');
               %fprintf(fid,'%18s %18s %18s %18s %18s\n','UTM_X(m)','UTM_Y(m)','Z(mgal)', 'Longitude', 'Latitude');               
              for i=1:length(x2)
                  WR2=[x2(i),y2(i),z2(i)];
              fprintf(fid,'%18f %18f %18f\n', WR2);
              end
              fclose(fid);
              
               fid=fopen('Filtered_Layer_Gravity.dat','w');
               %fprintf(fid,'%18s %18s %18s %18s %18s\n','UTM_X(m)','UTM_Y(m)','Z(mgal)', 'Longitude', 'Latitude');               
              for i=1:length(x22)
                  WR3=[x22(i),y22(i),z22(i)];
              fprintf(fid,'%18f %18f %18f\n', WR3);
              end
              fclose(fid);


        end

        % Button pushed function: CheckButton
        function Check(app, event)
        m=str2double(app.mEditField.Value); %defining m and n    
        if isnan(m) %If NaN is given for m show error dialog
            f=errordlg('m is empty','Warning!')
            else
                app.Lamp_3.Color = [0 1 0];
            end
             n=str2double(app.nEditField.Value); %defining m and n
            if isnan(n) %If NaN is given for n show error dialog
            f=errordlg('n is empty','Warning!')
            else
                app.Lamp_4.Color = [0 1 0];
            end
            
             degree=str2double(app.degreeEditField.Value); %defining degree
            if isnan(degree) %If NaN is given for degree show error dialog
            f=errordlg('degree is empty','Warning!')
            else
                app.Lamp_5.Color = [0 1 0];
            end
            
            sumappLampColor=app.Lamp_5.Color+app.Lamp_4.Color+app.Lamp_3.Color;
            
            if sumappLampColor==[0 3 0]
            app.StatusTextArea.Value = {'Prepare yourself to press "RUN"'};
            else
            app.StatusTextArea.Value = {'Opps! Something is missing'};
            end
        end

        % Button pushed function: RestartButton
        function restart(app, event)
             app.Lamp_3.Color = [1 0 0];
            app.Lamp.Color = [1 0 0];
            app.Lamp_2.Color = [1 0 0];
            app.Lamp_4.Color = [1 0 0];
            app.Lamp_5.Color = [1 0 0];
            
            app.FAAEditField.Value = ' ';
            app.LayerGravityEditField.Value = ' ';
            app.mEditField.Value=' ';
            app.nEditField.Value=' ';
            app.degreeEditField.Value=' ';
            app.StatusTextArea.Value = {'Please Press Check!'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse1, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.FAAEditFieldLabel = uilabel(app.UIFigure);
            app.FAAEditFieldLabel.Text = 'FAA';
            app.FAAEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @browse2, true);
            app.BrowseButton_2.Text = {'Browse'; ''};
            app.LayerGravityEditFieldLabel = uilabel(app.UIFigure);
            app.LayerGravityEditFieldLabel.Text = 'Layer Gravity';
            app.LayerGravityEditField = uieditfield(app.UIFigure, 'text');
            app.GridsizePanel = uipanel(app.UIFigure);
            app.GridsizePanel.Title = 'Gridsize';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'text');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'text');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.degreeEditFieldLabel = uilabel(app.UIFigure);
            app.degreeEditFieldLabel.Text = 'degree';
            app.degreeEditField = uieditfield(app.UIFigure, 'text');
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runbutton, true);
            app.RunButton.Text = {'Run'; ''};
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @Check, true);
            app.CheckButton.Text = 'Check!';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Value = {'Please Press Check!'};
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @restart, true);
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = w_equalization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end